classdef Moving_Comapny_Robots_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MovingCompanyRobotsUIFigure  matlab.ui.Figure
        BotBlueToothConnectionPanel  matlab.ui.container.Panel
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Bot1DropDownLabel            matlab.ui.control.Label
        Bot1DropDown                 matlab.ui.control.DropDown
        Bot2DropDownLabel            matlab.ui.control.Label
        Bot2DropDown                 matlab.ui.control.DropDown
        Bot3DropDownLabel            matlab.ui.control.Label
        Bot3DropDown                 matlab.ui.control.DropDown
        Bot4DropDownLabel            matlab.ui.control.Label
        Bot4DropDown                 matlab.ui.control.DropDown
        ConnectButton_1              matlab.ui.control.Button
        ConnectButton_2              matlab.ui.control.Button
        ConnectButton_3              matlab.ui.control.Button
        ConnectButton_4              matlab.ui.control.Button
        ReScanButton                 matlab.ui.control.Button
        SpeedCalibrationButton       matlab.ui.control.Button
        ControlsPanel                matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        FormationControlTab          matlab.ui.container.Tab
        BoxButton                    matlab.ui.control.Button
        CylinderButton               matlab.ui.control.Button
        TButton                      matlab.ui.control.Button
        NavigationTab                matlab.ui.container.Tab
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        SetDestinationLabel          matlab.ui.control.Label
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        BeginButton                  matlab.ui.control.Button
        ManualTab                    matlab.ui.container.Tab
        BotDropDownLabel             matlab.ui.control.Label
        BotDropDown                  matlab.ui.control.DropDown
        WASDControlButton            matlab.ui.control.StateButton
        ForwardButton                matlab.ui.control.Button
        RotateRight90Button          matlab.ui.control.Button
        RotateLeft90Button           matlab.ui.control.Button
        TicksEditFieldLabel          matlab.ui.control.Label
        TicksEditField               matlab.ui.control.NumericEditField
        Panel                        matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        ResetButton                  matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        NavigationTab_2              matlab.ui.container.Tab
        Map                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        
       % Bluetooth Sockets and grabbing scanned namees from instrument toolbox
       Bot1bt; Bot2bt; Bot3bt; Bot4bt;BOTval;BOT;
       btNames = instrhwinfo('Bluetooth');
       
       %U used to update Textbox in UI
       textarray;
       t = 0;
       
       % WASD value used to confirm manual control with WASD
       wasdvalue;
       
       % Initilize Occupancy Grid for Bots to fallow edit map.xls to change the map
       xy = xlsread('map.xlsx')
       
       % Navigation Parameters
       destx;desty;
       
       %Forward encoder values *100
       encval=1;read;

    end


    methods (Access = public)
        
        % Function to add text to textbox
        function addText(app,msg)
            
            app.t = app.t+1;
           
            app.textarray{app.t} = msg;
            
            app.textarray = fliplr(app.textarray);
            
            app.TextArea.Value = app.textarray;
                
        end
    
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            set(app.Bot1DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot2DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot3DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot4DropDown,'Items',app.btNames.RemoteNames);
            
           % setOccupancy(app.map,app.xy,1);
            map = robotics.BinaryOccupancyGrid(logical(app.xy),10);
            show(map,"Parent",app.Map);

        end

        % Button pushed function: ConnectButton_1
        function ConnectButton_1Pushed(app, event)
           
            
            app.Bot1DropDown.Enable = 'off';
            app.ConnectButton_1.Enable = 'off';
            addText(app,'Connecting to Bot 1....');
            pause(.01);
            try
                app.Bot1bt = Bluetooth(app.Bot1DropDown.Value,1);
                fopen(app.Bot1bt); 
                app.Bot1bt.Timeout = 120;
                addText(app,'Bot 1: CONNECTED');
                fwrite(app.Bot1bt,1);
                app.Lamp1.Color = 'green'; 
            catch
                app.Bot1DropDown.Enable = "on";
                app.ConnectButton_1.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 1');
            end
             
        end

        % Button pushed function: ConnectButton_3
        function ConnectButton_3Pushed(app, event)
           
            
            app.Bot3DropDown.Enable = "off";
            app.ConnectButton_3.Enable = 'off';
            addText(app,'Connecting to Bot 3....');
            pause(.01);
            try
                app.Bot3bt = Bluetooth(app.Bot3DropDown.Value,1);
                fopen(app.Bot3bt);
                app.Bot3bt.Timeout = 120;
                addText(app,'Bot 3: CONNECTED');
                fwrite(app.Bot3bt,1);
                app.Lamp3.Color = 'green';
            catch
                app.Bot3DropDown.Enable = "on";
                app.ConnectButton_3.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 3');             
            end
            
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            
            
            app.Bot2DropDown.Enable = "off";
            app.ConnectButton_2.Enable = 'off';
            addText(app,'Connecting to Bot 2....');
            pause(.01);
            try
                app.Bot2bt = Bluetooth(app.Bot2DropDown.Value,1);
                fopen(app.Bot2bt);
                app.Bot2bt.Timeout = 120;
                addText(app,'Bot 2: CONNECTED');
                fwrite(app.Bot2bt,1);
                app.Lamp2.Color = 'green';
            catch
                app.Bot2DropDown.Enable = "on";
                app.ConnectButton_2.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 2');              
            end
            
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
           
            
            app.Bot4DropDown.Enable = "off";
            app.ConnectButton_4.Enable = 'off';
            addText(app,'Connecting to Bot 4....');
            pause(.01); 
            try
                app.Bot4bt = Bluetooth(app.Bot4DropDown.Value,1);
                fopen(app.Bot4bt); 
                app.Bot4bt.Timeout = 120;
                addText(app,'Bot 4: CONNECTED');
                fwrite(app.Bot4bt,1);
                app.Lamp4.Color = 'green';
            catch                
                app.Bot4DropDown.Enable = "on";
                app.ConnectButton_4.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 4');
            end
            
        end

        % Value changed function: WASDControlButton
        function WASDControlButtonValueChanged(app, event)
            app.wasdvalue = app.WASDControlButton.Value;        % Update wasdvalue to true or false to let keymovement be pressed
        end

        % Key press function: MovingCompanyRobotsUIFigure
        function MovingCompanyRobotsUIFigureKeyPress(app, event)
            key = event.Key;
            
         
            if app.wasdvalue == 1
                switch key
                    case 'w'
                        fwrite(app.Bot1bt,2);
                    case 'a'
                        fwrite(app.Bot1bt,5);
                    case 's'
                        fwrite(app.Bot1bt,3);
                    case 'd'
                        fwrite(app.Bot1bt,4);    
                end
            end
            
        end

        % Key release function: MovingCompanyRobotsUIFigure
        function MovingCompanyRobotsUIFigureKeyRelease(app, event)
           fwrite(app.Bot1bt,0);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.destx = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.desty = value;
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            
        end

        % Button pushed function: ReScanButton
        function ReScanButtonPushed(app, event)
            addText(app,'Resanning for Bluetooth Devices...');
            app.btNames = instrhwinfo('Bluetooth');
            set(app.Bot1DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot2DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot3DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot4DropDown,'Items',app.btNames.RemoteNames);
            
            app.Lamp1.Color = 'red';                
            app.Bot1DropDown.Enable = "on";    
            app.ConnectButton_1.Enable = 'on';
            app.Lamp2.Color = 'red';                
            app.Bot2DropDown.Enable = "on";    
            app.ConnectButton_2.Enable = 'on';
            app.Lamp3.Color = 'red';                
            app.Bot3DropDown.Enable = "on";    
            app.ConnectButton_3.Enable = 'on';
            app.Lamp4.Color = 'red';                
            app.Bot4DropDown.Enable = "on";    
            app.ConnectButton_4.Enable = 'on';
            addText(app,'SCAN COMPLETE');
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %Forward certrain ticks            
            if app.BOTval == 0 %% ALL Bots
                 fwrite(app.Bot1bt,uint8([102,app.encval]));
                 fwrite(app.Bot2bt,uint8([102,app.encval]));
                 fwrite(app.Bot3bt,uint8([102,app.encval]));
                 %fwrite(app.Bot4bt,uint8([102,app.encval]));
            end
            fwrite(app.BOT,uint8([102,app.encval])); %% Specific Bot 
        end

        % Button pushed function: SpeedCalibrationButton
        function SpeedCalibrationButtonPushed(app, event)
            %Speed Calibration
                %All Bots
            if app.BOTval == 0
                                %Bot1
                addText(app,'Starting Speed Calibration for Bot 1');
                fwrite(app.Bot1bt,115); %Speed Calibration
                app.read = fread(app.Bot1bt,1);
                if app.read == 99
                addText(app,'Speed Calibration Bot 1: COMPLETE');
                app.read = 0;
                end
                                %Bot2
                addText(app,'Starting Speed Calibration for Bot 2');
                fwrite(app.Bot2bt,115); %Speed Calibration
                app.read = fread(app.Bot2bt,1);
                if app.read == 99
                addText(app,'Speed Calibration Bot 2: COMPLETE');
                app.read = 0;
                end
                                %Bot3
                addText(app,'Starting Speed Calibration for Bot 3');
                fwrite(app.Bot3bt,115); %Speed Calibration
                app.read = fread(app.Bot3bt,1);
                if app.read == 99
                addText(app,'Speed Calibration Bot 3: COMPLETE');
                app.read = 0;
                end
%                                 %Bot4
%                 addText(app,'Starting Speed Calibration for Bot 4');
%                 fwrite(app.Bot4bt,115); %Speed Calibration
%                 app.read = fread(app.Bot1bt,1);
%                 if app.read == 99
%                 addText(app,'Speed Calibration Bot 4: COMPLETE');
%                 app.read = 0;
%                 end
            end
            %%Specific Bot
            addText(app,'Starting Speed Calibration');
            fwrite(app.BOT,115); %Speed Calibration
            app.read = fread(app.Bot1bt,1);
           if app.read == 99
            addText(app,'Speed Calibration: COMPLETE');
            app.read = 0;
           end
           
        end

        % Value changed function: TicksEditField
        function TicksEditFieldValueChanged(app, event)
            value = app.TicksEditField.Value; %number of ticks
            app.encval = value;
        end

        % Button pushed function: RotateRight90Button
        function RotateRight90ButtonPushed(app, event)
            %Rotate Right some tiks
            if app.BOTval == 0%% ALL Bots
                 fwrite(app.Bot1bt,uint8([114,app.encval]));
                 fwrite(app.Bot2bt,uint8([114,app.encval]));
                 fwrite(app.Bot3bt,uint8([114,app.encval]));
                 %fwrite(app.Bot4bt,uint8([114,app.encval]));
            end
            fwrite(app.BOT,uint8([114,app.encval]));%% Specific Bot 
        end

        % Button pushed function: RotateLeft90Button
        function RotateLeft90ButtonPushed(app, event)
            %Rotate Left some Tiks           
            if app.BOTval == 0%% ALL Bots
                 fwrite(app.Bot1bt,uint8([108,app.encval]));
                 fwrite(app.Bot2bt,uint8([108,app.encval]));
                 fwrite(app.Bot3bt,uint8([108,app.encval]));
                 %fwrite(app.Bot4bt,uint8([108,app.encval]));
            end
            fwrite(app.BOT,uint8([108,app.encval]));%% Specific Bot 
        end

        % Value changed function: BotDropDown
        function BotDropDownValueChanged(app, event)
            value = app.BotDropDown.Value;
            app.BOTval = value;
            
            switch app.BOTval
                case 1  %BOT1
                    app.BOT = app.Bot1bt;
                case 2  %BOT2
                    app.BOT = app.Bot2bt;
                case 3  %BOT3
                    app.BOT = app.Bot3bt;
                case 4  %BOT4
                    app.BOT = app.Bot4bt;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MovingCompanyRobotsUIFigure = uifigure;
            app.MovingCompanyRobotsUIFigure.Name = 'Moving Company Robots';
            app.MovingCompanyRobotsUIFigure.KeyPressFcn = createCallbackFcn(app, @MovingCompanyRobotsUIFigureKeyPress, true);
            app.MovingCompanyRobotsUIFigure.KeyReleaseFcn = createCallbackFcn(app, @MovingCompanyRobotsUIFigureKeyRelease, true);
            app.BotBlueToothConnectionPanel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.BotBlueToothConnectionPanel.Title = 'Bot BlueTooth Connection';
            app.Lamp1 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp2 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp3 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp4 = uilamp(app.BotBlueToothConnectionPanel);
            app.Bot1DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot1DropDownLabel.Text = 'Bot 1';
            app.Bot1DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot2DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot2DropDownLabel.Text = 'Bot 2';
            app.Bot2DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot3DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot3DropDownLabel.Text = 'Bot 3';
            app.Bot3DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot4DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot4DropDownLabel.Text = 'Bot 4';
            app.Bot4DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.ConnectButton_1 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_1.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_1Pushed, true);
            app.ConnectButton_1.Text = 'Connect';
            app.ConnectButton_2 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.ConnectButton_3 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_3Pushed, true);
            app.ConnectButton_3.Text = 'Connect';
            app.ConnectButton_4 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Text = 'Connect';
            app.ReScanButton = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ReScanButton.ButtonPushedFcn = createCallbackFcn(app, @ReScanButtonPushed, true);
            app.ReScanButton.Text = 'ReScan';
            app.SpeedCalibrationButton = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.SpeedCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedCalibrationButtonPushed, true);
            app.SpeedCalibrationButton.Text = 'Speed Calibration';
            app.ControlsPanel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.TabGroup = uitabgroup(app.ControlsPanel);
            app.FormationControlTab = uitab(app.TabGroup);
            app.FormationControlTab.Title = 'Formation Control';
            app.BoxButton = uibutton(app.FormationControlTab, 'push');
            app.BoxButton.Text = 'Box';
            app.CylinderButton = uibutton(app.FormationControlTab, 'push');
            app.CylinderButton.Text = 'Cylinder';
            app.TButton = uibutton(app.FormationControlTab, 'push');
            app.TButton.Text = 'T';
            app.NavigationTab = uitab(app.TabGroup);
            app.NavigationTab.Title = 'Navigation';
            app.XEditFieldLabel = uilabel(app.NavigationTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.NavigationTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.SetDestinationLabel = uilabel(app.NavigationTab);
            app.SetDestinationLabel.Text = 'Set Destination';
            app.YEditFieldLabel = uilabel(app.NavigationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.NavigationTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.BeginButton = uibutton(app.NavigationTab, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.BotDropDownLabel = uilabel(app.ManualTab);
            app.BotDropDownLabel.Text = 'Bot';
            app.BotDropDown = uidropdown(app.ManualTab);
            app.BotDropDown.Items = {'All', '1', '2', '3', '4'};
            app.BotDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.BotDropDown.ValueChangedFcn = createCallbackFcn(app, @BotDropDownValueChanged, true);
            app.BotDropDown.Value = '0';
            app.WASDControlButton = uibutton(app.ManualTab, 'state');
            app.WASDControlButton.ValueChangedFcn = createCallbackFcn(app, @WASDControlButtonValueChanged, true);
            app.WASDControlButton.Text = 'WASD Control';
            app.ForwardButton = uibutton(app.ManualTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.RotateRight90Button = uibutton(app.ManualTab, 'push');
            app.RotateRight90Button.ButtonPushedFcn = createCallbackFcn(app, @RotateRight90ButtonPushed, true);
            app.RotateRight90Button.Text = 'Rotate Right 90';
            app.RotateLeft90Button = uibutton(app.ManualTab, 'push');
            app.RotateLeft90Button.ButtonPushedFcn = createCallbackFcn(app, @RotateLeft90ButtonPushed, true);
            app.RotateLeft90Button.Text = 'Rotate Left 90';
            app.TicksEditFieldLabel = uilabel(app.ManualTab);
            app.TicksEditFieldLabel.Text = 'Ticks';
            app.TicksEditField = uieditfield(app.ManualTab, 'numeric');
            app.TicksEditField.ValueChangedFcn = createCallbackFcn(app, @TicksEditFieldValueChanged, true);
            app.Panel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.Panel.Title = 'Panel';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.Text = 'Reset ';
            app.TabGroup2 = uitabgroup(app.MovingCompanyRobotsUIFigure);
            app.NavigationTab_2 = uitab(app.TabGroup2);
            app.NavigationTab_2.Title = 'Navigation';
            app.Map = uiaxes(app.NavigationTab_2);
            title(app.Map, '')
            xlabel(app.Map, 'X')
            ylabel(app.Map, 'Y')
            app.Map.XLim = [0 10];
            app.Map.YLim = [0 10];
            app.Map.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Map.XTickLabel = '';
            app.Map.YTick = [0 1 2 3 4 5 6 7 8 9 10];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Moving_Comapny_Robots_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MovingCompanyRobotsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MovingCompanyRobotsUIFigure)
        end
    end
end